classdef simGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ZustandsruckfuhrungPanel        matlab.ui.container.Panel
        BeobachtereigenwerteLabel       matlab.ui.control.Label
        BeobachterCheckBox              matlab.ui.control.CheckBox
        l4                              matlab.ui.control.EditField
        l3                              matlab.ui.control.EditField
        l2                              matlab.ui.control.EditField
        l1                              matlab.ui.control.EditField
        STOPButton                      matlab.ui.control.StateButton
        SimulierenButton                matlab.ui.control.StateButton
        AnfangswertePanel               matlab.ui.container.Panel
        Label_15                        matlab.ui.control.Label
        Label_14                        matlab.ui.control.Label
        x4                              matlab.ui.control.EditField
        x3                              matlab.ui.control.EditField
        x2                              matlab.ui.control.EditField
        x1                              matlab.ui.control.EditField
        ArbeitspunktPendel2ButtonGroup  matlab.ui.container.ButtonGroup
        AP2_0                           matlab.ui.control.RadioButton
        AP2_1                           matlab.ui.control.RadioButton
        ArbeitspunktPendel1Panel        matlab.ui.container.Panel
        AP1                             matlab.ui.control.Slider
        LQRPanel                        matlab.ui.container.Panel
        PoledesgeschlossenenRegelkreisesPanel  matlab.ui.container.Panel
        GridLayout3_2                   matlab.ui.container.GridLayout
        ReglerK_CL_Poles                matlab.ui.control.Label
        ReglerKPanel                    matlab.ui.container.Panel
        GridLayout3                     matlab.ui.container.GridLayout
        ReglerK_Values                  matlab.ui.control.Label
        BerechneKButton                 matlab.ui.control.StateButton
        Panel                           matlab.ui.container.Panel
        GridLayout2                     matlab.ui.container.GridLayout
        r11                             matlab.ui.control.EditField
        MatrixQPanel                    matlab.ui.container.Panel
        GridLayout                      matlab.ui.container.GridLayout
        q22                             matlab.ui.control.EditField
        Label_13                        matlab.ui.control.Label
        Label_12                        matlab.ui.control.Label
        Label_11                        matlab.ui.control.Label
        Label_10                        matlab.ui.control.Label
        Label_9                         matlab.ui.control.Label
        Label_8                         matlab.ui.control.Label
        Label_7                         matlab.ui.control.Label
        Label_6                         matlab.ui.control.Label
        Label_5                         matlab.ui.control.Label
        Label_4                         matlab.ui.control.Label
        Label_3                         matlab.ui.control.Label
        q44                             matlab.ui.control.EditField
        q33                             matlab.ui.control.EditField
        q11                             matlab.ui.control.EditField
        Label_2                         matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: BerechneKButton
        function BerechneKButtonValueChanged(app, event)
            Q = diag([str2double(app.q11.Value), str2double(app.q22.Value), str2double(app.q33.Value), str2double(app.q44.Value)]);
            R = str2double(app.r11.Value);
            AP1 = app.AP1.Value*pi/180;
            if app.AP2_0.Value
                AP2 = 0;
            elseif app.AP2_1.Value
                AP2 = pi;
            else
                AP2 = 0;
            end
            
            AP = [AP1, 0, AP2, 0];
            
            [f_m, h_m] = nonlinear_model();
    
        	[A, B, C, D, M_AP] = linearisierung(f_m, h_m, AP);
            
        	[K, poleRK] = berechneLQR(A, B, Q, R);
            
            app.ReglerK_Values.Text = ['[', num2str(K), ']'];


            app.ReglerK_CL_Poles.Text = ['[', num2str(poleRK', 4), ']'];
            
        end

        % Value changed function: SimulierenButton
        function Simulieren(app, event)
            stPendel = ladePendel();
       
            
            stObs.switch=app.BeobachterCheckBox.Value;
            stObs.poles = [...
               str2double(app.l1.Value),...
               str2double(app.l2.Value),...
               str2double(app.l3.Value),...
               str2double(app.l4.Value)]
            stObs.x0 = [...
                str2double(app.x1), ...
                str2double(app.x2),...
                str2double(app.x3),...
                str2double(app.x4)]
            
            AP1 = app.AP1.Value*pi/180;
            if app.AP2_0.Value
                AP2 = 0;
            elseif app.AP2_1.Value
                AP2 = pi;
            else
                AP2 = 0;
            end
            
            AP = [AP1, 0, AP2, 0];
            
            [f, h] = nonlinear_model();
            [stObs.A, stObs.B, stObs.C, stObs.D, M_AP] = linearisierung(f,h,AP);
            
            stObs.L = berechneBeobachter(stObs.A, stObs.C, stObs.poles)';
            [vT, mX, mXobs] = runPendel(stPendel, AP,K,x0,stObs);

            animierePendel(vT, mX, stPendel, app.UIAxes, false);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Axis')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.LQRPanel = uipanel(app.UIFigure);
            app.LQRPanel.Title = 'LQR';
            app.MatrixQPanel = uipanel(app.LQRPanel);
            app.MatrixQPanel.Title = 'Matrix Q';
            app.GridLayout = uigridlayout(app.MatrixQPanel);
            app.Label_2 = uilabel(app.GridLayout);
            app.Label_2.Text = '0';
            app.q11 = uieditfield(app.GridLayout, 'text');
            app.q11.Value = '1';
            app.q33 = uieditfield(app.GridLayout, 'text');
            app.q33.Value = '1';
            app.q44 = uieditfield(app.GridLayout, 'text');
            app.q44.Value = '1';
            app.Label_3 = uilabel(app.GridLayout);
            app.Label_3.Text = '0';
            app.Label_4 = uilabel(app.GridLayout);
            app.Label_4.Text = '0';
            app.Label_5 = uilabel(app.GridLayout);
            app.Label_5.Text = '0';
            app.Label_6 = uilabel(app.GridLayout);
            app.Label_6.Text = '0';
            app.Label_7 = uilabel(app.GridLayout);
            app.Label_7.Text = '0';
            app.Label_8 = uilabel(app.GridLayout);
            app.Label_8.Text = '0';
            app.Label_9 = uilabel(app.GridLayout);
            app.Label_9.Text = '0';
            app.Label_10 = uilabel(app.GridLayout);
            app.Label_10.Text = '0';
            app.Label_11 = uilabel(app.GridLayout);
            app.Label_11.Text = '0';
            app.Label_12 = uilabel(app.GridLayout);
            app.Label_12.Text = '0';
            app.Label_13 = uilabel(app.GridLayout);
            app.Label_13.Text = '0';
            app.q22 = uieditfield(app.GridLayout, 'text');
            app.q22.Value = '1';
            app.Panel = uipanel(app.LQRPanel);
            app.Panel.Title = 'Panel';
            app.GridLayout2 = uigridlayout(app.Panel);
            app.r11 = uieditfield(app.GridLayout2, 'text');
            app.r11.Value = '1';
            app.BerechneKButton = uibutton(app.LQRPanel, 'state');
            app.BerechneKButton.ValueChangedFcn = createCallbackFcn(app, @BerechneKButtonValueChanged, true);
            app.BerechneKButton.Text = 'Berechne K';
            app.ReglerKPanel = uipanel(app.LQRPanel);
            app.ReglerKPanel.Title = 'Regler K';
            app.GridLayout3 = uigridlayout(app.ReglerKPanel);
            app.ReglerK_Values = uilabel(app.GridLayout3);
            app.ReglerK_Values.Text = '[                                                                                     ]';
            app.PoledesgeschlossenenRegelkreisesPanel = uipanel(app.LQRPanel);
            app.PoledesgeschlossenenRegelkreisesPanel.Title = 'Pole des geschlossenen Regelkreises';
            app.GridLayout3_2 = uigridlayout(app.PoledesgeschlossenenRegelkreisesPanel);
            app.ReglerK_CL_Poles = uilabel(app.GridLayout3_2);
            app.ReglerK_CL_Poles.Text = '[                                                                                                                 ]';
            app.ArbeitspunktPendel1Panel = uipanel(app.UIFigure);
            app.ArbeitspunktPendel1Panel.Title = 'Arbeitspunkt  Pendel 1';
            app.AP1 = uislider(app.ArbeitspunktPendel1Panel);
            app.AP1.Limits = [0 180];
            app.ArbeitspunktPendel2ButtonGroup = uibuttongroup(app.UIFigure);
            app.ArbeitspunktPendel2ButtonGroup.Title = 'Arbeitspunkt Pendel 2';
            app.AP2_1 = uiradiobutton(app.ArbeitspunktPendel2ButtonGroup);
            app.AP2_1.Text = '180°';
            app.AP2_0 = uiradiobutton(app.ArbeitspunktPendel2ButtonGroup);
            app.AP2_0.Text = '0°';
            app.AP2_0.Value = true;
            app.AnfangswertePanel = uipanel(app.UIFigure);
            app.AnfangswertePanel.Title = 'Anfangswerte';
            app.x1 = uieditfield(app.AnfangswertePanel, 'text');
            app.x1.Value = '0';
            app.x2 = uieditfield(app.AnfangswertePanel, 'text');
            app.x2.Value = '0';
            app.x3 = uieditfield(app.AnfangswertePanel, 'text');
            app.x3.Value = '0';
            app.x4 = uieditfield(app.AnfangswertePanel, 'text');
            app.x4.Value = '0';
            app.Label_14 = uilabel(app.AnfangswertePanel);
            app.Label_14.Text = '[';
            app.Label_15 = uilabel(app.AnfangswertePanel);
            app.Label_15.Text = ']';
            app.SimulierenButton = uibutton(app.UIFigure, 'state');
            app.SimulierenButton.ValueChangedFcn = createCallbackFcn(app, @Simulieren, true);
            app.SimulierenButton.Text = 'Simulieren';
            app.STOPButton = uibutton(app.UIFigure, 'state');
            app.STOPButton.Text = 'STOP';
            app.ZustandsruckfuhrungPanel = uipanel(app.UIFigure);
            app.ZustandsruckfuhrungPanel.Title = 'Zustandsruckfuhrung';
            app.l1 = uieditfield(app.ZustandsruckfuhrungPanel, 'text');
            app.l1.Value = '-10';
            app.l2 = uieditfield(app.ZustandsruckfuhrungPanel, 'text');
            app.l2.Value = '-11';
            app.l3 = uieditfield(app.ZustandsruckfuhrungPanel, 'text');
            app.l3.Value = '-12';
            app.l4 = uieditfield(app.ZustandsruckfuhrungPanel, 'text');
            app.l4.Value = '-13';
            app.BeobachterCheckBox = uicheckbox(app.ZustandsruckfuhrungPanel);
            app.BeobachterCheckBox.Text = 'Beobachter';
            app.BeobachtereigenwerteLabel = uilabel(app.ZustandsruckfuhrungPanel);
            app.BeobachtereigenwerteLabel.Text = 'Beobachtereigenwerte';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = simGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end